classdef FEM_2D_Schrodinger_Equation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CaptureplotsButton             matlab.ui.control.Button
        StepsEditField                 matlab.ui.control.NumericEditField
        StepsEditFieldLabel            matlab.ui.control.Label
        InitialconditionButton         matlab.ui.control.Button
        PertubationtheoryButton        matlab.ui.control.Button
        omegaEditField                 matlab.ui.control.NumericEditField
        omegaEditFieldLabel            matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        HarmonicButton                 matlab.ui.control.RadioButton
        PotentialwellButton            matlab.ui.control.RadioButton
        E2EditField                    matlab.ui.control.NumericEditField
        E2EditFieldLabel               matlab.ui.control.Label
        E1EditField                    matlab.ui.control.NumericEditField
        E1EditFieldLabel               matlab.ui.control.Label
        E_0EditField                   matlab.ui.control.NumericEditField
        E_0EditFieldLabel              matlab.ui.control.Label
        TimestepEditField              matlab.ui.control.NumericEditField
        TimestepEditFieldLabel         matlab.ui.control.Label
        ModeChoice                     matlab.ui.container.TabGroup
        PotentialTab                   matlab.ui.container.Tab
        ModulussquaredSwitch           matlab.ui.control.Switch
        ModulussquaredSwitchLabel      matlab.ui.control.Label
        SeparatefigureCheckBox         matlab.ui.control.CheckBox
        RecalculateButton_2            matlab.ui.control.Button
        V0Input                        matlab.ui.control.NumericEditField
        V_0Label                       matlab.ui.control.Label
        n_finInput                     matlab.ui.control.NumericEditField
        n_finEditFieldLabel            matlab.ui.control.Label
        n_stInput                      matlab.ui.control.NumericEditField
        n_stEditFieldLabel             matlab.ui.control.Label
        PotChoice                      matlab.ui.container.TabGroup
        InfinitepotentialwellTab       matlab.ui.container.Tab
        nyEditField                    matlab.ui.control.NumericEditField
        nyEditFieldLabel               matlab.ui.control.Label
        nxEditField                    matlab.ui.control.NumericEditField
        nxEditFieldLabel               matlab.ui.control.Label
        TheoreticalplotButton          matlab.ui.control.Button
        HarmonicpotentialTab           matlab.ui.container.Tab
        nyEditField_2                  matlab.ui.control.NumericEditField
        nyEditField_2Label             matlab.ui.control.Label
        nxEditField_2                  matlab.ui.control.NumericEditField
        nxEditField_2Label             matlab.ui.control.Label
        TheoreticalplotButton_2        matlab.ui.control.Button
        CoulombpotentialTab            matlab.ui.container.Tab
        FinitebarrierTab               matlab.ui.container.Tab
        y_barr2EditField               matlab.ui.control.NumericEditField
        y_barr2EditFieldLabel          matlab.ui.control.Label
        y_barr1EditField               matlab.ui.control.NumericEditField
        y_barr1EditFieldLabel          matlab.ui.control.Label
        x_barr2EditField               matlab.ui.control.NumericEditField
        x_barr2EditFieldLabel          matlab.ui.control.Label
        x_barr1EditField               matlab.ui.control.NumericEditField
        x_barr1EditFieldLabel          matlab.ui.control.Label
        SteppotentialTab               matlab.ui.container.Tab
        DoublewelltrapTab              matlab.ui.container.Tab
        sigmaInput                     matlab.ui.control.NumericEditField
        sigmaLabel                     matlab.ui.control.Label
        U0Input                        matlab.ui.control.NumericEditField
        U0Label                        matlab.ui.control.Label
        PowerlawpotentialTab           matlab.ui.container.Tab
        PowerEditField                 matlab.ui.control.NumericEditField
        PowerEditFieldLabel            matlab.ui.control.Label
        GeometryTab                    matlab.ui.container.Tab
        RecalculateButton              matlab.ui.control.Button
        MorepointsincenterSwitch       matlab.ui.control.Switch
        MorepointsincenterSwitchLabel  matlab.ui.control.Label
        GeomChoice                     matlab.ui.container.TabGroup
        RectangleTab                   matlab.ui.container.Tab
        CrossTab                       matlab.ui.container.Tab
        d_yInput                       matlab.ui.control.NumericEditField
        d_yLabel                       matlab.ui.control.Label
        d_xInput                       matlab.ui.control.NumericEditField
        d_xLabel                       matlab.ui.control.Label
        TriangleTab                    matlab.ui.container.Tab
        EllipseTab                     matlab.ui.container.Tab
        y_bInput                       matlab.ui.control.NumericEditField
        y_bLabel                       matlab.ui.control.Label
        y_aInput                       matlab.ui.control.NumericEditField
        y_aLabel                       matlab.ui.control.Label
        x_bInput                       matlab.ui.control.NumericEditField
        x_bEditFieldLabel              matlab.ui.control.Label
        x_aInput                       matlab.ui.control.NumericEditField
        x_aEditFieldLabel              matlab.ui.control.Label
        HmaxInput                      matlab.ui.control.NumericEditField
        h0Label                        matlab.ui.control.Label
        h_avgEditField                 matlab.ui.control.NumericEditField
        h_avgEditFieldLabel            matlab.ui.control.Label
        CombinestatesButton            matlab.ui.control.Button
        State2EditField                matlab.ui.control.NumericEditField
        State2EditFieldLabel           matlab.ui.control.Label
        State1EditField                matlab.ui.control.NumericEditField
        State1EditFieldLabel           matlab.ui.control.Label
        HoldCheckBox                   matlab.ui.control.CheckBox
        nAEditField                    matlab.ui.control.NumericEditField
        nAEditFieldLabel               matlab.ui.control.Label
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        DonecalculatingLamp            matlab.ui.control.Lamp
        DonecalculatingLampLabel       matlab.ui.control.Label
        h_finEditField                 matlab.ui.control.NumericEditField
        h_finEditFieldLabel            matlab.ui.control.Label
        h_stEditField                  matlab.ui.control.NumericEditField
        h_stEditFieldLabel             matlab.ui.control.Label
        EnergyanalysisButton           matlab.ui.control.Button
        Composite                      matlab.ui.control.UIAxes
        EnergyError                    matlab.ui.control.UIAxes
        Mesh                           matlab.ui.control.UIAxes
        potentialplot                  matlab.ui.control.UIAxes
        ModSquared                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pdenodes 
        potential 
        pdeelements
        geometry
        rep 
        hrep 
        energies
        surface 
        eigenstates 
        deltavect
        eigenvalues 
        boundary
        avglength 
        hermitelist
        colorindex 
    end
    
    methods (Access = private)
        
        
        function [K,E] = gen_eig2D(app,nodes,elements,pot,n_st,n_fin,j)
            %Calculates the generalised eigenvectors and eigenvalues.
            
            N = length(elements);
            M = length(nodes);
            
            A = zeros(M,M);
            B = zeros(M,M);
            C = zeros(M,M);
            delta = zeros(1,N);
            
            %This loop fills the matrices A,B,C and delta.
            for ii = 1:N
                coord = zeros(3,2);
                ind = [elements(ii,1); elements(ii,2); elements(ii,3)];
                
                %The coordinates of the three considered vertices.
                for jj = 1:2
                    coord(1,jj) = nodes(ind(1),jj);
                    coord(2,jj) = nodes(ind(2),jj);
                    coord(3,jj) = nodes(ind(3),jj);
                end
                
                beta = zeros(1,3);
                gamma = zeros(1,3);
                delta(ii) = abs((coord(2,1)-coord(1,1))*(coord(3,2)-coord(1,2))-...
                    (coord(2,2)-coord(1,2))*(coord(3,1)-coord(1,1)));
                beta(1) = (coord(2,2)-coord(3,2))/delta(ii);
                beta(2) = (coord(3,2)-coord(1,2))/delta(ii);
                beta(3) = (coord(1,2)-coord(2,2))/delta(ii);
                gamma(1) = (coord(3,1)-coord(2,1))/delta(ii);
                gamma(2) = (coord(1,1)-coord(3,1))/delta(ii);
                gamma(3) = (coord(2,1)-coord(1,1))/delta(ii);
                
                if delta(ii) ~= 0 %this is a precaution but this doesn't occur anymore
                    for jj = 1:3
                        for kk = 1:3   
                            if jj == kk
                                integ = (delta(ii)/6);
                                m = find(ismembertol(j,[coord(jj,1),coord(jj,2)],0.005,'ByRows',true)==1, 1);
                                if isempty(m)
                                    integV = (delta(ii)/6)*pot(coord(jj,1),coord(jj,2));
                                else
                                    integV = 1000000000;
                                end       
                                
                            else
                                integ = 0;
                                integV = 0;    
                                
                            end
                            A(ind(jj),ind(kk)) = A(ind(jj),ind(kk)) + (delta(ii)/2)*...
                                                (beta(jj)*beta(kk)+gamma(jj)*gamma(kk));
                            B(ind(jj),ind(kk)) = B(ind(jj),ind(kk)) + integV;
                            C(ind(jj),ind(kk)) = C(ind(jj),ind(kk)) + integ;
                        end
                    end
                else
                    for jj = 1:3
                        m = find(ismembertol(j,[coord(jj,1),coord(jj,2)],0.0001,'ByRows',true)==1, 1);
                        if ~isempty(m)
                            B(ind(jj),ind(jj)) = B(ind(jj),ind(jj))+1000000000;
                        end
                    end
                end
            end
            
            D = A + 2.*B;
            
            %Check if D doesn't contain NaN values.
            TF = isnan(D);
            P = find(TF, 1);
            if isempty(P)
                [K,E] = eig(D,2*C,'vector');
            else
                K = zeros(M,M);
                E = zeros(M);
            end
            
            if strcmpi(app.potential,"Infinite potential well")==1
                %This makes the eigenvectors 2 and 3 (degenerate) in the
                %form that is theoretically expected.
                if app.x_bInput.Value-app.x_aInput.Value == app.y_bInput.Value-app.y_aInput.Value
                    fun1 = K(:,2);
                    fun2 = K(:,3);
                    K(:,2) = (1/sqrt(2))*(fun1+fun2);
                    K(:,3) = (1/sqrt(2))*(fun1-fun2);
                end
            end
            
            if app.rep == 0 || abs(app.hrep-app.h_finEditField.Value)<0.01
                %Normalize the requested eigenstates
                for ii = n_st:n_fin
                    psi = K(:,ii);
                    norm = 0;
                    for jj = 1:N
                        ind = [elements(jj,1); elements(jj,2); elements(jj,3)];
                        norm = norm + (abs(delta(jj))/6)*(psi(ind(1)).^2+psi(ind(2)).^2+psi(ind(3)).^2);
                    end
                    K(:,ii) = psi/sqrt(norm);
                    if mean(psi)<0
                        K(:,ii) = -K(:,ii);
                    end
                end
            end
            app.surface = sum(abs(delta))/2;
            app.avglength = sum(sqrt(delta))/N;
            app.eigenstates = K;
            app.deltavect = delta;
            app.eigenvalues = E;
        end
        
        function pot = inf_pot(app,x,y,x_a,x_b,y_a,y_b,V0)
            pot = V0;
        end
        
        function pot = harm_pot(app,x,y,x_a,x_b,y_a,y_b,V0)
            x_c = (x_a+x_b)/2;
            y_c = (y_a+y_b)/2;
            pot = 0.5*V0.*((x-x_c).^2+(y-y_c).^2);
        end
        
        function pot = coul_pot(app,x,y,x_a,x_b,y_a,y_b,V0)
            x_c = (x_a+x_b)/2;
            y_c = (y_a+y_b)/2;
            pot = -V0./sqrt((x-x_c).^2+(y-y_c).^2);
        end
        
        function pot = barr_pot(app,x,y,V0,x_barr1,x_barr2,y_barr1,y_barr2)
            if (x_barr1<x && x<x_barr2)&&(y_barr1<y && y<y_barr2)
                    pot = V0;
            else
                    pot = 0;
            end
        end
        
        function pot = step_pot(app,x,y,V0)
            if  y<0
                pot = 0;
            else
                pot = V0;
            end
        end
        
        function [p,t]=distmesh2d(app,fd,fh,h0,bbox,pfix,varargin)
            %DISTMESH2D 2-D Mesh Generator using Distance Functions.
            %   [P,T]=DISTMESH2D(FD,FH,H0,BBOX,PFIX,FPARAMS)
            %
            %      P:         Node positions (Nx2)
            %      T:         Triangle indices (NTx3)
            %      FD:        Distance function d(x,y)
            %      FH:        Scaled edge length function h(x,y)
            %      H0:        Initial edge length
            %      BBOX:      Bounding box [xmin,ymin; xmax,ymax]
            %      PFIX:      Fixed node positions (NFIXx2)
            %      FPARAMS:   Additional parameters passed to FD and FH
            
            %   Copyright (C) 2004-2012 Per-Olof Persson.
            
            dptol=.005; ttol=.1; Fscale=1.2; deltat=.2; geps=.001*h0; deps=sqrt(eps)*h0;
            densityctrlfreq=120;
            cla(app.Mesh)
            cla(app.Mesh, 'reset')
            
            if isempty(varargin)
                varargin = {0};
            end
            
            % 1. Create initial distribution in bounding box (equilateral triangles)
            [x,y]=meshgrid(bbox(1,1):h0:bbox(2,1),bbox(1,2):h0*sqrt(3)/2:bbox(2,2));
            x(2:2:end,:)=x(2:2:end,:)+h0/2;                      % Shift even rows
            p=[x(:),y(:)];                                       % List of node coordinates
            
            % 2. Remove points outside the region, apply the rejection method
            p=p(feval(fd,p,varargin{:})<geps,:);                 % Keep only d<0 points
            r0=1./feval(fh,p,varargin{:}).^2;                    % Probability to keep point
            p=p(rand(size(p,1),1)<r0./max(r0),:);                % Rejection method
            if ~isempty(pfix), p=setdiff(p,pfix,'rows'); end     % Remove duplicated nodes
            pfix=unique(pfix,'rows'); nfix=size(pfix,1);
            p=[pfix; p];                                         % Prepend fix points
            N=size(p,1);                                         % Number of points N
            
            count=0;
            pold=inf;                                            % For first iteration
            axis(app.Mesh,'equal');
            while 1
              count=count+1;
              % 3. Retriangulation by the Delaunay algorithm
              if max(sqrt(sum((p-pold).^2,2))/h0)>ttol           % Any large movement?
                pold=p;                                          % Save current positions
                t=delaunayn(p);                                  % List of triangles
                pmid=(p(t(:,1),:)+p(t(:,2),:)+p(t(:,3),:))/3;    % Compute centroids
                t=t(feval(fd,pmid,varargin{:})<-geps,:);         % Keep interior triangles
                % 4. Describe each bar by a unique pair of nodes
                bars=[t(:,[1,2]);t(:,[1,3]);t(:,[2,3])];         % Interior bars duplicated
                bars=unique(sort(bars,2),'rows');                % Bars as node pairs
                % 5. Graphical output of the current mesh
                if (mod(count,10)==0 && app.rep == 0)
                    cla(app.Mesh)
                    patch('vertices',p,'faces',t,'edgecol','k','facecol',[.8,.9,1],'Parent',app.Mesh);
                    drawnow
                end
              end
            
              % 6. Move mesh points based on bar lengths L and forces F
              barvec=p(bars(:,1),:)-p(bars(:,2),:);              % List of bar vectors
              L=sqrt(sum(barvec.^2,2));                          % L = Bar lengths
              hbars=feval(fh,(p(bars(:,1),:)+p(bars(:,2),:))/2,varargin{:});
              L0=hbars*Fscale*sqrt(sum(L.^2)/sum(hbars.^2));     % L0 = Desired lengths
              
              % Density control - remove points that are too close
              if mod(count,densityctrlfreq)==0 && any(L0>2*L)
                  p(setdiff(reshape(bars(L0>2*L,:),[],1),1:nfix),:)=[];
                  N=size(p,1); pold=inf;
                  continue;
              end
              
              F=max(L0-L,0);                                     % Bar forces (scalars)
              Fvec=F./L*[1,1].*barvec;                           % Bar forces (x,y components)
              Ftot=full(sparse(bars(:,[1,1,2,2]),ones(size(F))*[1,2,1,2],[Fvec,-Fvec],N,2));
              Ftot(1:size(pfix,1),:)=0;                          % Force = 0 at fixed points
              p=p+deltat*Ftot;                                   % Update node positions
            
              % 7. Bring outside points back to the boundary
              d=feval(fd,p,varargin{:}); ix=d>0;                 % Find points outside (d>0)
              dgradx=(feval(fd,[p(ix,1)+deps,p(ix,2)],varargin{:})-d(ix))/deps; % Numerical
              dgrady=(feval(fd,[p(ix,1),p(ix,2)+deps],varargin{:})-d(ix))/deps; %    gradient
              dgrad2=dgradx.^2+dgrady.^2;
              p(ix,:)=p(ix,:)-[d(ix).*dgradx./dgrad2,d(ix).*dgrady./dgrad2];    % Project
            
              % 8. Termination criterion: All interior nodes move less than dptol (scaled)
              if max(sqrt(sum(deltat*Ftot(d<-geps,:).^2,2))/h0)<dptol
                  patch('vertices',p,'faces',t,'edgecol','k','facecol',[.8,.9,1],'Parent',app.Mesh);
                  drawnow
                  break; 
              end
            end
            app.boundary = [p(abs(feval(fd,p,varargin{:}))<dptol/2,1),p(abs(feval(fd,p,varargin{:}))<dptol/2,2)];
        end
        
        function d=dcircle(app,p,xc,yc,r)

            %   Copyright (C) 2004-2012 Per-Olof Persson.
            
            d=sqrt((p(:,1)-xc).^2+(p(:,2)-yc).^2)-r;
            
        end
        
        function pot = double_well(app,x,y,x_a,x_b,y_a,y_b,sig,U0,V0)
            x_c = (x_a+x_b)/2;
            y_c = (y_a+y_b)/2;
            pot = 0.5*V0.*((x-x_c).^2+(y-y_c).^2) + U0*exp(-(x-x_c).^2 /(2*sig.^2));
        end
        
        
        function pot = power_pot(app,x,y,x_a,x_b,y_a,y_b,V0,pwr)
            x_c = (x_a+x_b)/2;
            y_c = (y_a+y_b)/2;
            pot = 0.5*V0.*sqrt((x-x_c).^2+(y-y_c).^2).^pwr;
        end
        
        function h = huniform(app,p,varargin)

            %   Copyright (C) 2004-2012 Per-Olof Persson.
            
            h=ones(size(p,1),1);
        end
        
        function d=dpoly(app,p,pv)

            %   Copyright (C) 2004-2012 Per-Olof Persson.
            
            np=size(p,1);
            nvs=size(pv,1)-1;
            
            
            ds=zeros(np,nvs);
            for iv=1:nvs
              ds(:,iv)=donesegment(app,p,pv(iv:iv+1,:));
            end
            d=min(ds,[],2);
            
            d=(-1).^(inpolygon(p(:,1),p(:,2),pv(:,1),pv(:,2))).*d;
        end
        
        function ds=donesegment(app,p,pv)
            %   Copyright (C) 2004-2012 Per-Olof Persson.
            
            e=ones(size(p,1),1);
            
            v=diff(pv,1);
            w=p-e*pv(1,:);
            
            c1=sum(w.*v(e,:),2);
            c2=sum(v(e,:).^2,2);
            
            ds=0*e;
            
            ix=c1<=0;
            ds(ix)=sqrt(sum((p(ix,:)-pv(1*ones(sum(ix),1),:)).^2,2));
            
            ix=c1>=c2;
            ds(ix)=sqrt(sum((p(ix,:)-pv(2*ones(sum(ix),1),:)).^2,2));
            
            ix=c1>0 & c2>c1;
            nix=sum(ix);
            if nix>0
             Pb=ones(nix,1)*pv(1,:)+c1(ix)./c2(ix)*v;
             ds(ix)=sqrt(sum((p(ix,:)-Pb).^2,2));
            end
        end
        
        
        function v=simpvol(app,p,t)
            %SIMPVOL Simplex volume.
            %   V=SIMPVOL(P,T)
            
            %   Copyright (C) 2004-2012 Per-Olof Persson.
            
            switch size(p,2)
             case 1
              d12=p(t(:,2),:)-p(t(:,1),:);
              v=d12;
             case 2
              d12=p(t(:,2),:)-p(t(:,1),:);
              d13=p(t(:,3),:)-p(t(:,1),:);
              v=(d12(:,1).*d13(:,2)-d12(:,2).*d13(:,1))/2;
             case 3
              d12=p(t(:,2),:)-p(t(:,1),:);
              d13=p(t(:,3),:)-p(t(:,1),:);
              d14=p(t(:,4),:)-p(t(:,1),:);
              v=dot(cross(d12,d13,2),d14,2)/6;
             otherwise
              v=zeros(size(t,1),1);
              for ii=1:size(t,1)
                A=zeros(size(p,2)+1);
                A(:,1)=1;
                for jj=1:size(p,2)+1
                  A(jj,2:end)=p(t(ii,jj),:);
                end
                v(ii)=det(A);
              end
              v=v/factorial(size(p,2));
            end
        end
        
        
        function reset_plots(app)
            cla(app.ModSquared)
            cla(app.ModSquared,'reset')
            cla(app.potentialplot)
            cla(app.potentialplot,'reset')
        end
        
        function name_plots(app)
            %Gives the correct titles, axis labels and axis settings to all
            %plots.
            
            app.ModSquared.XLimitMethod = 'tight';
            app.ModSquared.YLimitMethod = 'tight';
            if strcmpi(app.ModulussquaredSwitch.Value,'on')==1
                app.ModSquared.Title.String = 'Eigenstates (modulus squared)';
            else
                app.ModSquared.Title.String = 'Eigenstates';
            end
            xlabel(app.ModSquared,'x');
            ylabel(app.ModSquared,'y');
            zlabel(app.ModSquared,'z');
            app.potentialplot.XLimitMethod = 'tight';
            app.potentialplot.YLimitMethod = 'tight';
            app.potentialplot.Title.String = 'Potential';
            xlabel(app.potentialplot,'x');
            ylabel(app.potentialplot,'y');
            zlabel(app.potentialplot,'z');
            app.Mesh.Title.String = 'Mesh';
            xlabel(app.Mesh,'x');
            ylabel(app.Mesh,'y');
            if app.SeparatefigureCheckBox.Value ==1
                figure(1)
                ax = gca;
                ax.XLimitMethod = 'tight';
                ax.YLimitMethod = 'tight';
                xlabel(ax,'x');
                ylabel(ax,'y');
                zlabel(ax,'z');
                if strcmpi(app.ModulussquaredSwitch.Value,'on')==1
                    ax.Title.String = 'Eigenstates (modulus squared)';
                else
                    ax.Title.String = 'Eigenstates';
                end
                
                figure(5)
                ax = gca;
                ax.XLimitMethod = 'tight';
                ax.YLimitMethod = 'tight';
                ax.Title.String = 'Potential';
                xlabel(ax,'x');
                ylabel(ax,'y');
                zlabel(ax,'z');
                
                figure(2)
                ax = gca;
                ax.Title.String = 'Mesh';
                xlabel(ax,'x');
                ylabel(ax,'y');
                axis equal
            end
        end
    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Runs at the startup of the app
            app.UIFigure.Scrollable=1;
            app.rep = 0;
            app.colorindex = 0;
            syms x
            app.hermitelist = hermiteH([0:1:100], x);
            UpdateMesh(app)
        end

        % Callback function: PotChoice, RecalculateButton_2
        function refreshplot(app, event)
            %This function runs if the potential changes and after a change
            %in the mesh has been made.
            app.DonecalculatingLamp.Color = [1,0,0];
            pause(0.01)
            
            x_a = app.x_aInput.Value;
            x_b = app.x_bInput.Value;
            y_a = app.y_aInput.Value;
            y_b = app.y_bInput.Value;
            
            nodes = app.pdenodes;
            elements = app.pdeelements;
            x_shp = nodes(:,1);
            y_shp = nodes(:,2);
            
            j = app.boundary;
            
            app.potential = app.PotChoice.SelectedTab.Title;
            n_st = app.n_stInput.Value;     
            n_fin = app.n_finInput.Value;
            
            reset_plots(app);
            
            V0 = app.V0Input.Value;
            U0 = app.U0Input.Value;
            sig = app.sigmaInput.Value;
            pwr = app.PowerEditField.Value;
            
            %Depending on the choice of potential, the function 'pot' is
            %changed.
            switch app.potential
                case 'Infinite potential well'
                    pot = @(x,y) inf_pot(app,x,y,x_a,x_b,y_a,y_b,V0);
                    app.HarmonicButton.Enable = 'off';
                    app.PotentialwellButton.Value = 1;
                case 'Harmonic potential'
                    pot = @(x,y) harm_pot(app,x,y,x_a,x_b,y_a,y_b,V0);
                    app.HarmonicButton.Enable = 'on';
                    
                case 'Coulomb potential'
                    pot = @(x,y) coul_pot(app,x,y,x_a,x_b,y_a,y_b,V0);
                    app.HarmonicButton.Enable = 'on';

                case 'Finite barrier'
                    x_barr1 = app.x_barr1EditField.Value;
                    x_barr2 = app.x_barr2EditField.Value;
                    y_barr1 = app.y_barr1EditField.Value;
                    y_barr2 = app.y_barr2EditField.Value;
                    
                    pot = @(x,y) barr_pot(app,x,y,V0,x_barr1,x_barr2,y_barr1,y_barr2);
                    app.HarmonicButton.Enable = 'off';
                    app.PotentialwellButton.Value = 1;
                    
                case 'Step potential'
                    pot = @(x,y) step_pot(app,x,y,V0);
                    app.HarmonicButton.Enable = 'off';
                    app.PotentialwellButton.Value = 1;
                case 'Double-well trap'
                    pot = @(x,y) double_well(app,x,y,x_a,x_b,y_a,y_b,sig,U0,V0);
                    app.HarmonicButton.Enable = 'on';
                case 'Power-law potential'
                    pot = @(x,y) power_pot(app,x,y,x_a,x_b,y_a,y_b,V0,pwr);
                    app.HarmonicButton.Enable = 'on';
            end
            
            %Check if perturbation theory is possible for this choice of
            %mesh and potential
            if strcmpi(app.HarmonicButton.Enable, 'off') && strcmpi(app.PotentialwellButton.Enable, 'off')
                app.PertubationtheoryButton.Enable = 'off';
            else
                app.PertubationtheoryButton.Enable = 'on';
            end
            
            %Array that contains the points to plot for the potential
            poti = zeros(1,length(x_shp));
            for ii = 1:length(x_shp)
                poti(ii) = pot(x_shp(ii),y_shp(ii));
            end
            
            %Calculates eigenvectors and eigenvalues
            [eig, E] = gen_eig2D(app,nodes,elements,pot,n_st,n_fin,j);
            
            app.energies = E;
            if app.rep == 0
                %Plot the potential, if 'seperate figure' is selected, it
                %also gets plotted on a seperate figure.
                trisurf(app.pdeelements,x_shp,y_shp, ...
                    poti,'Facealpha',0.5,'FaceColor','interp','parent',app.potentialplot)
                if app.SeparatefigureCheckBox.Value ==1
                    figure(5)
                    trisurf(app.pdeelements,x_shp,y_shp, ...
                        poti,'Facealpha',0.5,'FaceColor','interp')
                end
                
                %Plot the eigenvectors at the correct height. If mod. squared
                %is turned on the square of the eigenvectors will be
                %plotted.
                colors = [[0 0.4470 0.7410];[0.8500 0.3250 0.0980];[0.9290 0.6940 0.1250];...
                    [0.4940 0.1840 0.5560];[0.4660 0.6740 0.1880];[0.3010 0.7450 0.9330];[0.6350 0.0780 0.1840]];
                for ii = n_st:n_fin
                    psi = eig(:,ii);
                    if strcmpi(app.ModulussquaredSwitch.Value,'on')==1
                        trisurf(app.pdeelements, nodes(:,1),nodes(:,2),psi.^2 + E(ii),psi.^2,'Facealpha',0.5, ...
                        'FaceColor',colors(mod(ii-n_st,7)+1,:),'Parent',app.ModSquared);
                    else
                        trisurf(app.pdeelements, nodes(:,1),nodes(:,2),psi + E(ii),psi,'Facealpha',0.5, ...
                        'FaceColor',colors(mod(ii-n_st,7)+1,:),'Parent',app.ModSquared);
                    end
                    hold(app.ModSquared, 'on');
                end
                if app.SeparatefigureCheckBox.Value ==1
                    figure(1)
                    for ii = n_st:n_fin
                        psi = eig(:,ii);
                        if strcmpi(app.ModulussquaredSwitch.Value,'on')==1
                            trisurf(app.pdeelements, nodes(:,1),nodes(:,2),psi.^2 + E(ii),psi.^2,'Facealpha',0.5, ...
                            'FaceColor',colors(mod(ii-n_st,7)+1,:));
                        else
                            trisurf(app.pdeelements, nodes(:,1),nodes(:,2),psi + E(ii),psi,'Facealpha',0.5, ...
                            'FaceColor',colors(mod(ii-n_st,7)+1,:));
                        end
                        hold on;
                    end
                end
            end
            
            if app.SeparatefigureCheckBox.Value ==1
                figure(2)
                patch('vertices',nodes,'faces',elements,'edgecol','k','facecol',[.8,.9,1]);
                drawnow
            end
            
            app.E_0EditField.Value = E(1);
            
            if app.rep == 0
                app.DonecalculatingLamp.Color = [0,1,0];
            end
            %Give accurate titles and axis labels
            name_plots(app)
            
        end

        % Button pushed function: TheoreticalplotButton, 
        % TheoreticalplotButton_2
        function TheoreticalplotButtonPushed(app, event)
            %Will plot the expected eigenvector at the expected energy
            %level for the harmonic oscillator or the infinite potential
            %well.
            app.potential = app.PotChoice.SelectedTab.Title;
            app.DonecalculatingLamp.Color = [1,0,0];
            x_a = app.x_aInput.Value;
            x_b = app.x_bInput.Value;
            y_a = app.y_aInput.Value;
            y_b = app.y_bInput.Value;
            x_c = 0.5*(x_a+x_b);
            y_c = 0.5*(y_a+y_b);
            Lx = x_b - x_a;
            Ly = y_b - y_a;
            
            V0 = app.V0Input.Value;
            
            X = app.pdenodes(:,1);
            Nx = length(X);
            Y = app.pdenodes(:,2);
            Ny = length(Y);
            
            switch app.potential
                case 'Infinite potential well'
                    nx = app.nxEditField.Value;  
                    ny = app.nyEditField.Value;
                    E = 0.5*((nx*pi/Lx)^2+(ny*pi/Ly)^2)+V0;
                    phi = @(s,m) (2/((Lx*Ly)^0.5) .*  sin(nx*pi*(s-x_c-Lx/2)/Lx)*sin(ny*pi*(m-y_c-Ly/2)/Ly)).^2;
                    vect = zeros(Nx,1);
                    for ii = 1:Nx
                        vect(ii) = phi(X(ii),Y(ii))+E;
                    end
                    plot3(app.ModSquared,X,Y,vect,'.','MarkerSize',10)
                        
                case 'Harmonic potential'
                    nx = app.nxEditField_2.Value;  
                    ny = app.nyEditField_2.Value;
                    w = sqrt(V0);
                    E = w*(nx + ny +1);
                    syms x
                    
                    %Using the Hermite polynomials as a polynomial vector
                    %was found to be faster.
                    px = sym2poly(hermiteH(nx,x));
                    py = sym2poly(hermiteH(ny,x));
                    phi =  @(s,m) (w/pi)^(0.5) * (1/((2^(nx+ny))*factorial(nx)*factorial(ny)))^0.5 ...
                            .*polyval(px,sqrt(w).*m).*polyval(py,sqrt(w) .*s).*exp(-0.5*w.*(s.^2 + m.^2));
                    
                    vect = zeros(Nx,1);
                    for ii = 1:Nx
                        vect(ii) = phi(X(ii)-x_c,Y(ii)-y_c).^2 + E;
                    end
                    plot3(app.ModSquared,X,Y,vect,'.','MarkerSize',10)
                    
            end
            
            %Plots the theoretical energy on the energy analysis plot.
            if app.SeparatefigureCheckBox.Value ==1
                figure(1)
                plot3(X,Y,vect,'.','MarkerSize',10)
                figure(4)
                plot(logspace(0.1,5),E.*ones(50,1),'linewidth',1.5,'linestyle','--')
                hold on
            end
            plot(app.EnergyError,logspace(0.1,5),E.*ones(50,1),'linewidth',1.5,'linestyle','--')
            hold(app.EnergyError,'on')
            app.DonecalculatingLamp.Color = [0,1,0];
        end

        % Callback function: GeomChoice, RecalculateButton
        function UpdateMesh(app, event)
            %If changes to the mesh need to be made, this function is
            %called. It calculates a new mesh and then refers to
            %refresplot(app)
            
            if app.rep == 0
                app.DonecalculatingLamp.Color = [1,0,0];
                pause(0.01)
            end
            app.geometry = app.GeomChoice.SelectedTab.Title;
            x_a = app.x_aInput.Value;
            x_b = app.x_bInput.Value;
            y_a = app.y_aInput.Value;
            y_b = app.y_bInput.Value;
            x_c = 0.5*(x_a + x_b);
            y_c = 0.5*(y_a + y_b);
            d_x = app.d_xInput.Value;
            d_y = app.d_yInput.Value;
            
            %app.rep is explained in Energyanalisisbuttonpushed(app)
            if app.rep == 0
                hmax = app.HmaxInput.Value;
            else
                hmax = app.hrep;
            end 
            
            %If the choice for more points in the center is made, a
            %different function for fh is used. It increases linearly
            %w.r.t. the center.
            if strcmpi(app.MorepointsincenterSwitch.Value,'on')==1
                hmax = hmax/2;
                if strcmpi(app.geometry,'Triangle')
                    x_c2 = (x_a+x_c+x_b)/3;
                    y_c2 = (y_a+y_b+y_a)/3;
                    fh=@(p,s) 0.6*hmax+0.02*abs(dcircle(app,p,x_c2,y_c2,0));
                else
                    fh=@(p,s) 0.6*hmax+0.02*abs(dcircle(app,p,x_c,y_c,0));
                end
            else
                fh = @(p,s) huniform(app,p,s);
            end
            fd = @(p,s) dpoly(app,p,s);
            
            %pv will contain the vertices of the polygon, these are fixed
            %points for the mesh.
            switch app.geometry
                case 'Rectangle'
                    pv=[x_a y_a;x_a,y_b;x_b,y_b;x_b,y_a;x_a y_a];
                    [p,t]=distmesh2d(app,fd,fh,hmax,[x_a,y_a; x_b,y_b],pv,pv);
                    app.PotentialwellButton.Enable = 'on';
                    app.TheoreticalplotButton.Enable = 'on';
                case 'Cross'
                    pv=[x_a d_y/2;-d_x/2 d_y/2;-d_x/2 y_b;d_x/2 y_b;d_x/2 d_y/2; x_b d_y/2;
                        x_b -d_y/2;d_x/2 -d_y/2; d_x/2 y_a;-d_x/2 y_a; -d_x/2 -d_y/2;
                        x_a -d_y/2;x_a d_y/2];
                    [p,t]=distmesh2d(app,fd,fh,hmax,[x_a,y_a; x_b,y_b],pv,pv);
                    app.PotentialwellButton.Enable = 'off';
                    app.HarmonicButton.Value = 1;
                    app.TheoreticalplotButton.Enable = 'off';
                case 'Triangle'
                    pv=[x_a y_a;x_c,y_b;x_b,y_a;x_a y_a];
                    [p,t]=distmesh2d(app,fd,fh,hmax,[x_a,y_a; x_b,y_b],pv,pv);
                    app.PotentialwellButton.Enable = 'off';
                    app.HarmonicButton.Value = 1;
                    app.TheoreticalplotButton.Enable = 'off';
                case 'Ellipse'
                    fd=@(p,s) 4*((p(:,1)-x_c).^2 /(x_b-x_a)^2+(p(:,2)-y_c).^2 /(y_b-y_a)^2)-1;
                    [p,t]=distmesh2d(app,fd,fh,hmax,[x_a,y_a; x_b,y_b],[]);
                    app.PotentialwellButton.Enable = 'off';
                    app.HarmonicButton.Value = 1;
                    app.TheoreticalplotButton.Enable = 'off';
            end
            
            app.pdenodes = p;
            app.pdeelements = t;
            app.nEditField.Value = length(app.pdeelements);
            refreshplot(app);
            app.nAEditField.Value = length(app.pdeelements)/app.surface;
            app.h_avgEditField.Value = app.avglength;
            if app.rep == 0
                app.DonecalculatingLamp.Color = [0,1,0];
            end
        end

        % Button pushed function: EnergyanalysisButton
        function EnergyanalysisButtonPushed(app, event)
            %For h decreasing from h_st to h_fin this function calculates
            %the requested eigenvalues and plots them.
            
            if app.HoldCheckBox.Value == 0
                cla(app.EnergyError)
                cla(app.EnergyError,'reset')
                app.colorindex = 0;
            end
            app.DonecalculatingLamp.Color = [1,0,0];
            pause(0.01)
            
            %app.rep is 1 if the calculations have to be quickly
            %repeated; nothing will be plotted if it equals 1.
            app.rep = 1;
            app.hrep = app.h_stEditField.Value;
            
            %If the following value for h is used, the x-axis will be
            %approximately equidistant in log scale.
            h = (1/10)*log10(app.h_stEditField.Value/app.h_finEditField.Value);
            n_st = app.n_stInput.Value;
            n_fin = app.n_finInput.Value;
            diff = n_fin-n_st +1;
            Ematrix = zeros(11,diff);
            nlist = zeros(11,1);
            
            colors = [[0 0.4470 0.7410];[0.8500 0.3250 0.0980];[0.9290 0.6940 0.1250];...
                    [0.4940 0.1840 0.5560];[0.4660 0.6740 0.1880];...
                    [0.3010 0.7450 0.9330];[0.6350 0.0780 0.1840]];
            
            for nn = 1:11
                %For every value of h the mesh is updated and energies are
                %calculated.
                
                if nn == 11
                    app.rep = 0;
                    app.HmaxInput.Value = app.hrep;
                end
                UpdateMesh(app)
                Ematrix(nn,:) = app.energies(n_st:n_fin)';
                nlist(nn) = length(app.pdeelements);
                app.hrep = app.hrep *10^(-h);
                if nn ~= 1
                    for jj = 1:diff
                        plot(app.EnergyError,nlist(1:nn),Ematrix(1:nn,jj),'color', ...
                            colors(mod(app.colorindex+jj-1,7)+1,:),'Linewidth',1.5);
                        hold(app.EnergyError,'on');
                        title(app.EnergyError,'Energy analysis for decreasing h');
                        app.EnergyError.XLimitMethod = 'tight';
                        xlabel(app.EnergyError,'Number of elements')
                        ylabel(app.EnergyError,'Energy')
                        if app.SeparatefigureCheckBox.Value ==1
                            figure(4)
                            plot(nlist(1:nn),Ematrix(1:nn,jj),'color', ...
                            colors(mod(app.colorindex+jj-1,7)+1,:),'Linewidth',1.5);
                            hold on;
                            ax= gca;
                            ax.XScale = 'log';
                            title('Error analysis');
                            ax= gca;
                            ax.XLimitMethod = 'tight';
                            xlabel('Number of elements')
                            ylabel('Energy')
                        end
                    end
                    if nlist(1) ~= nlist(nn)
                        xlim(app.EnergyError,[nlist(1),nlist(nn)])
                        figure(4)
                        ax = gca;
                        xlim(ax,[nlist(1),nlist(nn)])
                    end 
                    app.EnergyError.XScale = 'log';
                end
            end    
            
            app.colorindex=app.colorindex + diff;
            title(app.EnergyError,'Energy analysis for decreasing h');
            app.EnergyError.XLimitMethod = 'tight';
            xlabel(app.EnergyError,'Number of elements')
            ylabel(app.EnergyError,'Energy')
            figure(4)
            title('Energy analysis for decreasing h');
            ax= gca;
            ax.XLimitMethod = 'tight';
            xlabel('Number of elements')
            ylabel('Energy')
            app.DonecalculatingLamp.Color = [0,1,0];
        end

        % Button pushed function: CombinestatesButton
        function CombinestatesButtonPushed(app, event)
            %This function combines two specified states into one state.
            %The combination is then plotted for subsequent values of time
            %and a gif is saved to the current MATLAB folder.
            
            app.DonecalculatingLamp.Color = [1,0,0];
            pause(0.01)
            n1 = app.State1EditField.Value; 
            n2 = app.State2EditField.Value;
            State1 = app.eigenstates(:,n1);
            E1 = app.eigenvalues(n1);
            E2 = app.eigenvalues(n2);
            State2 = app.eigenstates(:,n2);
            N = length(State1);
            delay = 0.55;
            
            %First, the two states are normalised.
            norm1 = 0;
            norm2 = 0;
            
            for jj = 1:N
                ind = [app.pdeelements(jj,1); app.pdeelements(jj,2); app.pdeelements(jj,3)];
                norm1 = norm1 + (abs(app.deltavect(jj))/6)*(State1(ind(1)).^2+State1(ind(2)).^2+State1(ind(3)).^2);
                norm2 = norm2 + (abs(app.deltavect(jj))/6)*(State2(ind(1)).^2+State2(ind(2)).^2+State2(ind(3)).^2);
            end
            State1 = State1/sqrt(norm1);
            State2 = State2/sqrt(norm2);
            zmax = max(State1+State2);
            
            cla(app.Composite)
            cla(app.Composite, 'reset')
            timestep = app.TimestepEditField.Value;
            t = figure(3);
            for k = 0:app.StepsEditField.Value
                %Wave will contain the correct combination of the states.
                wave = 0.5*abs(State1*exp(-1i*E1*k*timestep)+State2*exp(-1i*E2*k*timestep)).^2;
                
                trisurf(app.pdeelements,app.pdenodes(:,1),app.pdenodes(:,2), ...
                wave,'Facealpha',0.5,'FaceColor','interp','parent',app.Composite)
                title(app.Composite,append('Probability density state ', string(n1),' + state ', string(n2) ,' at time t=',string(k*timestep)))
                xlabel(app.Composite,'x')
                ylabel(app.Composite,'y')
                zlabel(app.Composite,'z')
                app.Composite.ZLim = [0 zmax];
                app.Composite.XLimitMethod = 'tight';
                app.Composite.YLimitMethod = 'tight';
                
                %This is also plotted to a seperate figure because this
                %provides better images for the gif.
                figure(3)
                trisurf(app.pdeelements,app.pdenodes(:,1),app.pdenodes(:,2), ...
                wave,'Facealpha',0.5,'FaceColor','interp')
                title(append('Probability density state ', string(n1),' + state ', string(n2) ,' at time t=',string(k*timestep)))
                xlabel('x')
                ylabel('y')
                zlabel('z')
                ax = gca;
                ax.ZLim = [0 zmax];
                ax.XLimitMethod = 'tight';
                ax.YLimitMethod = 'tight';
                
                print(['ZFrame ' num2str(k)], '-dpng', '-r500',t);
            end
            
            for k = 0:app.StepsEditField.Value
                %Construction of the gif.
                [A, ~] = imread(['ZFrame ' num2str(k) '.png']);
                [X, map] = rgb2ind(A, 256);
                if k == 0
                    imwrite(X, map, 'animation1.gif', 'gif', 'LoopCount', inf, 'DelayTime', delay)
                else
                    imwrite(X, map, 'animation1.gif', 'gif', 'WriteMode', 'append', 'DelayTime', delay)
                end
            end
            app.Composite.XLimitMethod = 'tight';
            app.Composite.YLimitMethod = 'tight';
            app.DonecalculatingLamp.Color = [0,1,0];
        end

        % Button pushed function: PertubationtheoryButton
        function PertubationtheoryButtonPushed(app, event)
            %This will perform the perturbation theory with respect to the
            %selected potential.
            
            app.DonecalculatingLamp.Color = [1,0,0];
            pause(0.01)
            x_a = app.x_aInput.Value;
            x_b = app.x_bInput.Value;
            y_a = app.y_aInput.Value;
            y_b = app.y_bInput.Value;
            x_c = 0.5*(x_a+x_b);
            y_c = 0.5*(y_a+y_b);
            Lx = x_b - x_a;
            Ly = y_b - y_a;
            
            V0 = app.V0Input.Value;
            U0 = app.U0Input.Value;
            sig = app.sigmaInput.Value;
            pwr = app.PowerEditField.Value;
            
            switch app.potential
                case 'Infinite potential well'
                    pot = @(x,y) inf_pot(app,x,y,x_a,x_b,y_a,y_b,V0);
                    
                case 'Harmonic potential'
                    pot = @(x,y) harm_pot(app,x,y,x_a,x_b,y_a,y_b,V0);
                    
                case 'Coulomb potential'
                    pot = @(x,y) coul_pot(app,x,y,x_a,x_b,y_a,y_b,V0);

                case 'Finite barrier'
                    x_barr1 = app.x_barr1EditField.Value;
                    x_barr2 = app.x_barr2EditField.Value;
                    y_barr1 = app.y_barr1EditField.Value;
                    y_barr2 = app.y_barr2EditField.Value;
                    
                    pot = @(x,y) barr_pot(app,x,y,V0,x_barr1,x_barr2,y_barr1,y_barr2);
                    
                case 'Step potential'
                    pot = @(x,y) step_pot(app,x,y,V0);
                case 'Double-well trap'
                    pot = @(x,y) double_well(app,x,y,x_a,x_b,y_a,y_b,sig,U0,V0);
                case 'Power-law potential'
                    pot = @(x,y) power_pot(app,x,y,x_a,x_b,y_a,y_b,V0,pwr);
            end
            
            %These are the unperturbed potentials:
            if app.PotentialwellButton.Value == 1
                E = 0.5*((pi/Lx)^2+(pi/Ly)^2);
                phi = @(s,m) 2/((Lx*Ly)^0.5) .*  sin(pi.*(s-x_c-Lx/2)./Lx).*sin(pi.*(m-y_c-Ly/2)./Ly);
            else
                V = app.omegaEditField.Value;
                w = sqrt(app.omegaEditField.Value);
                E = w;
                phi = @(s,m) (w/pi)^(0.5) .*exp(-0.5*w.*(s.^2 + m.^2));
                pot = @(s,m) pot(s,m) - harm_pot(app,s,m,x_a,x_b,y_a,y_b,V);
                syms x
            end
            
            %First order correction
            if strcmpi(app.potential, 'Finite barrier') == 1
                E1 = integral2(@(s,m) V0.*phi(s,m).^2,x_barr1,x_barr2,y_barr1,y_barr2);
            elseif strcmpi(app.potential, 'Step potential') == 1
                E1 = integral2(@(s,m) V0.*phi(s,m).^2,x_a,x_b,0,y_b);
            else
                E1 = integral2(@(s,m) pot(s,m).*(phi(s,m).^2),x_a,x_b,y_a,y_b);
            end
            app.E1EditField.Value = E+E1;
            
            %Second order correction
            E2 = 0;
            N = 20;
            for ii = 1:N
                for jj = 1:N
                    if (ii~=1)||(jj~=1)
                        if app.PotentialwellButton.Value == 1
                            E_it = 0.5*((ii*pi/Lx)^2+(jj*pi/Ly)^2);
                            phi_it = @(s,m) 2/((Lx*Ly)^0.5).*sin(ii*pi.*(s-x_c-Lx/2)./Lx).*sin(jj*pi.*(m-y_c-Ly/2)./Ly);  
                        else
                            nx = ii-1;
                            ny = jj-1;
                            E_it = w*(nx + ny +1);
                            
                            px = sym2poly(hermiteH(nx,x));
                            py = sym2poly(hermiteH(ny,x));
                            phi_it =  @(s,m) (w/pi)^(0.5) * (1/((2^(nx+ny))*factorial(nx)*factorial(ny)))^0.5 ...
                                    .*polyval(px,sqrt(w).*s).*polyval(py,sqrt(w) .*m).*exp(-0.5*w.*(s.^2 + m.^2));
                        end
                        if strcmpi(app.potential, 'Finite barrier') == 1
                            E2 = E2 + integral2(@(s,m) phi_it(s,m)*V0.*phi(s,m),x_barr1,x_barr2,y_barr1,y_barr2)^2 /(E-E_it);
                        elseif strcmpi(app.potential, 'Step potential') == 1
                            E2 = E2 + integral2(@(s,m) phi_it(s,m)*V0.*phi(s,m),x_a,x_b,0,y_b)^2 /(E-E_it);
                        else
                            E2 = E2 + integral2(@(s,m) phi_it(s,m).*pot(s,m).*phi(s,m),x_a,x_b,y_a,y_b)^2 /(E-E_it);
                        end
                    end
                end
            end
            app.E2EditField.Value = E+E1+E2;
            app.DonecalculatingLamp.Color = [0,1,0];
        end

        % Button pushed function: InitialconditionButton
        function InitialconditionButtonPushed(app, event)
            %Will attempt to model the expansion of the wavefunction after 
            %position measurement of a particle using the approximate
            %eigenstates.
            
            app.DonecalculatingLamp.Color = [1,0,0];
            pause(0.01)
            N = length(app.eigenstates(:,1));
            K = round(length(app.pdenodes(:,1))*9/10);
            coeff = zeros(1,K);
            
            %Here the initial wavefunction is defined
            D = 0.01;
            f = @(x,y) sqrt(1/(D*pi))*exp(-(x^2+y^2)/(2*D));
            zmax = sqrt(1/(D*pi));
            delay = 0.55;
            
            %The inner products of the initial wavefunction and the
            %eigenstates are calculated.
            for kk = 1:K
                norm = 0;
                state = app.eigenstates(:,kk);
                for jj = 1:N
                    ind = [app.pdeelements(jj,1); app.pdeelements(jj,2); app.pdeelements(jj,3)];
                    norm = norm + (abs(app.deltavect(jj))/6)*(state(ind(1)).^2+state(ind(2)).^2+state(ind(3)).^2);
                    coeff(kk)= coeff(kk) + abs(app.deltavect(jj))/6 *(state(ind(1))*f(app.pdenodes(ind(1),1),app.pdenodes(ind(1),2))...
                                +state(ind(2))*f(app.pdenodes(ind(2),1),app.pdenodes(ind(2),2))+state(ind(3))*f(app.pdenodes(ind(3),1),app.pdenodes(ind(3),2)));
                end
                app.eigenstates(:,kk) = state/sqrt(norm);
                coeff(kk) = coeff(kk)/sqrt(norm);
            end
            
            %This is similar to the 'initial condition' function.
            cla(app.Composite)
            cla(app.Composite, 'reset')
            timestep = app.TimestepEditField.Value;
            t = figure(3);
            for k = 0:app.StepsEditField.Value
                wave = zeros(N,1);
                for jj = 1:K
                    wave = wave + coeff(jj)*app.eigenstates(:,jj)*exp(-1i*app.energies(jj)*k*timestep);
                end
                
                norm = 0;
                for jj = 1:N
                    ind = [app.pdeelements(jj,1); app.pdeelements(jj,2); app.pdeelements(jj,3)];
                    norm = norm + (abs(app.deltavect(jj))/6)*(abs(wave(ind(1))).^2+abs(wave(ind(2))).^2+abs(wave(ind(3))).^2);
                end
                wave = abs(wave).^2 /norm;
                
                trisurf(app.pdeelements,app.pdenodes(:,1),app.pdenodes(:,2), ...
                wave,'Facealpha',0.5,'FaceColor','interp','parent',app.Composite)
                title(app.Composite,append('Modulus squared of the wavefunction of a particle starting at (x,y)=(0,0) at time t=',string(k*timestep)))
                xlabel(app.Composite,'x')
                ylabel(app.Composite,'y')
                zlabel(app.Composite,'z')
                app.Composite.ZLim = [0 zmax];
                app.Composite.XLimitMethod = 'tight';
                app.Composite.YLimitMethod = 'tight';

                figure(3)
                trisurf(app.pdeelements,app.pdenodes(:,1),app.pdenodes(:,2), ...
                wave,'Facealpha',0.5,'FaceColor','interp')
                title(append('Modulus squared of the wavefunction of a particle starting at (x,y)=(0,0) at time t=',string(k*timestep)))
                xlabel('x')
                ylabel('y')
                zlabel('z')
                ax = gca;
                ax.ZLim = [0 zmax];
                ax.XLimitMethod = 'tight';
                ax.YLimitMethod = 'tight';
                pause(0.001)
                
                print(['ZFrame ' num2str(k)], '-dpng', '-r500',t);
            end
            for k = 0:app.StepsEditField.Value
                [A, ~] = imread(['ZFrame ' num2str(k) '.png']);
                [X, map] = rgb2ind(A, 256);
                if k == 0
                    imwrite(X, map, 'animation1.gif', 'gif', 'LoopCount', inf, 'DelayTime', delay)
                else
                    imwrite(X, map, 'animation1.gif', 'gif', 'WriteMode', 'append', 'DelayTime', delay)
                end
            end
            app.Composite.XLimitMethod = 'tight';
            app.Composite.YLimitMethod = 'tight';
            app.DonecalculatingLamp.Color = [0,1,0];
        end

        % Button pushed function: CaptureplotsButton
        function CaptureplotsButtonPushed(app, event)
            %Takes images of the plots in the app in vector format.
            exportapp(app.UIFigure,'AApp.png')
            app.DonecalculatingLamp.Color = [1,0,0];
            pause(0.01)
            exportgraphics(app.ModSquared,'AMod_squared.eps','ContentType','vector')
            exportgraphics(app.Mesh,'AMesh.eps','ContentType','vector')
            exportgraphics(app.potentialplot,'Apotential.eps','ContentType','vector')
            exportgraphics(app.EnergyError,'AEnergyError.eps','ContentType','vector')
            exportgraphics(app.Composite,'AComposite.eps','ContentType','vector')
            app.DonecalculatingLamp.Color = [0,1,0];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Closes all plots if app is closed.
            close all
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ModSquared = uiaxes(app.UIFigure);
            title(app.ModSquared, 'Eigenstates (modulus squared)')
            xlabel(app.ModSquared, 'X')
            ylabel(app.ModSquared, 'Y')
            app.potentialplot = uiaxes(app.UIFigure);
            title(app.potentialplot, 'Potential')
            xlabel(app.potentialplot, 'X')
            ylabel(app.potentialplot, 'Y')
            app.Mesh = uiaxes(app.UIFigure);
            title(app.Mesh, 'Mesh')
            xlabel(app.Mesh, 'X')
            ylabel(app.Mesh, 'Y')
            zlabel(app.Mesh, 'Z')
            app.EnergyError = uiaxes(app.UIFigure);
            title(app.EnergyError, 'Error analysis')
            xlabel(app.EnergyError, 'X')
            ylabel(app.EnergyError, 'Y')
            zlabel(app.EnergyError, 'Z')
            app.EnergyError.XLimitMethod = 'tight';
            app.Composite = uiaxes(app.UIFigure);
            title(app.Composite, 'Composite')
            xlabel(app.Composite, 'X')
            ylabel(app.Composite, 'Y')
            zlabel(app.Composite, 'Z')
            app.Composite.ZLim = [0 1];
            app.Composite.XLimitMethod = 'tight';
            app.Composite.YLimitMethod = 'tight';
            app.EnergyanalysisButton = uibutton(app.UIFigure, 'push');
            app.EnergyanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyanalysisButtonPushed, true);
            app.EnergyanalysisButton.Tooltip = {'Will calculate the energies for decreasing values of h.'};
            app.EnergyanalysisButton.Text = 'Energy analysis';
            app.h_stEditFieldLabel = uilabel(app.UIFigure);
            app.h_stEditFieldLabel.Text = 'h_st';
            app.h_stEditField = uieditfield(app.UIFigure, 'numeric');
            app.h_stEditField.Tooltip = {'The highest value of h in the analysis.'};
            app.h_stEditField.Value = 0.5;
            app.h_finEditFieldLabel = uilabel(app.UIFigure);
            app.h_finEditFieldLabel.Text = 'h_fin';
            app.h_finEditField = uieditfield(app.UIFigure, 'numeric');
            app.h_finEditField.Tooltip = {'The lowest value of h in the analysis.'};
            app.h_finEditField.Value = 0.05;
            app.DonecalculatingLampLabel = uilabel(app.UIFigure);
            app.DonecalculatingLampLabel.Text = 'Done calculating';
            app.DonecalculatingLamp = uilamp(app.UIFigure);
            app.DonecalculatingLamp.Tooltip = {'If this is red, the calculation is still running.'};
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditField.Limits = [0 Inf];
            app.nEditField.Editable = 'off';
            app.nEditField.Tooltip = {'Amount of elements.'};
            app.nAEditFieldLabel = uilabel(app.UIFigure);
            app.nAEditFieldLabel.Text = 'n/A';
            app.nAEditField = uieditfield(app.UIFigure, 'numeric');
            app.nAEditField.Limits = [0 Inf];
            app.nAEditField.Editable = 'off';
            app.nAEditField.Tooltip = {'Elements divided by surface area.'};
            app.HoldCheckBox = uicheckbox(app.UIFigure);
            app.HoldCheckBox.Tooltip = {'If hold is on, the plot will not be cleared the next time the analysis is performed.'};
            app.HoldCheckBox.Text = 'Hold';
            app.State1EditFieldLabel = uilabel(app.UIFigure);
            app.State1EditFieldLabel.Text = 'State 1';
            app.State1EditField = uieditfield(app.UIFigure, 'numeric');
            app.State1EditField.Tooltip = {'Number of the state that will be combined with state 2.'};
            app.State1EditField.Value = 1;
            app.State2EditFieldLabel = uilabel(app.UIFigure);
            app.State2EditFieldLabel.Text = 'State 2';
            app.State2EditField = uieditfield(app.UIFigure, 'numeric');
            app.State2EditField.Tooltip = {'Number of the state that will be combined with state 1.'};
            app.State2EditField.Value = 3;
            app.CombinestatesButton = uibutton(app.UIFigure, 'push');
            app.CombinestatesButton.ButtonPushedFcn = createCallbackFcn(app, @CombinestatesButtonPushed, true);
            app.CombinestatesButton.Tooltip = {'Will combine the states specified on the left into one composite state with equal probabilities for either state.'};
            app.CombinestatesButton.Text = 'Combine states';
            app.h_avgEditFieldLabel = uilabel(app.UIFigure);
            app.h_avgEditFieldLabel.Text = 'h_avg';
            app.h_avgEditField = uieditfield(app.UIFigure, 'numeric');
            app.h_avgEditField.Limits = [0 Inf];
            app.h_avgEditField.Editable = 'off';
            app.h_avgEditField.Tooltip = {'Estimate for average edge-length.'};
            app.ModeChoice = uitabgroup(app.UIFigure);
            app.ModeChoice.Tooltip = {'Switch between potential- and geometry-mode'};
            app.PotentialTab = uitab(app.ModeChoice);
            app.PotentialTab.Title = 'Potential';
            app.PotChoice = uitabgroup(app.PotentialTab);
            app.PotChoice.Tooltip = {'Choose the potential that will be used.'};
            app.PotChoice.SelectionChangedFcn = createCallbackFcn(app, @refreshplot, true);
            app.InfinitepotentialwellTab = uitab(app.PotChoice);
            app.InfinitepotentialwellTab.Tooltip = {''; ''};
            app.InfinitepotentialwellTab.Title = 'Infinite potential well';
            app.TheoreticalplotButton = uibutton(app.InfinitepotentialwellTab, 'push');
            app.TheoreticalplotButton.ButtonPushedFcn = createCallbackFcn(app, @TheoreticalplotButtonPushed, true);
            app.TheoreticalplotButton.Tooltip = {'Plots the theoretically calculated results'};
            app.TheoreticalplotButton.Text = 'Theoretical plot';
            app.nxEditFieldLabel = uilabel(app.InfinitepotentialwellTab);
            app.nxEditFieldLabel.Text = 'nx';
            app.nxEditField = uieditfield(app.InfinitepotentialwellTab, 'numeric');
            app.nxEditField.Limits = [1 Inf];
            app.nxEditField.Tooltip = {'Energy-level for the x-part of the theoretical solution'};
            app.nxEditField.Value = 1;
            app.nyEditFieldLabel = uilabel(app.InfinitepotentialwellTab);
            app.nyEditFieldLabel.Text = 'ny';
            app.nyEditField = uieditfield(app.InfinitepotentialwellTab, 'numeric');
            app.nyEditField.Limits = [1 Inf];
            app.nyEditField.Tooltip = {'Energy-level for the y-part of the theoretical solution'};
            app.nyEditField.Value = 2;
            app.HarmonicpotentialTab = uitab(app.PotChoice);
            app.HarmonicpotentialTab.Title = 'Harmonic potential';
            app.TheoreticalplotButton_2 = uibutton(app.HarmonicpotentialTab, 'push');
            app.TheoreticalplotButton_2.ButtonPushedFcn = createCallbackFcn(app, @TheoreticalplotButtonPushed, true);
            app.TheoreticalplotButton_2.Tooltip = {'Plots the theoretically calculated results'};
            app.TheoreticalplotButton_2.Text = 'Theoretical plot';
            app.nxEditField_2Label = uilabel(app.HarmonicpotentialTab);
            app.nxEditField_2Label.Text = 'nx';
            app.nxEditField_2 = uieditfield(app.HarmonicpotentialTab, 'numeric');
            app.nxEditField_2.Limits = [0 Inf];
            app.nxEditField_2.Tooltip = {'Energy-level for the x-part of the theoretical solution'};
            app.nyEditField_2Label = uilabel(app.HarmonicpotentialTab);
            app.nyEditField_2Label.Text = 'ny';
            app.nyEditField_2 = uieditfield(app.HarmonicpotentialTab, 'numeric');
            app.nyEditField_2.Limits = [0 Inf];
            app.nyEditField_2.Tooltip = {'Energy-level for the y-part of the theoretical solution'};
            app.nyEditField_2.Value = 1;
            app.CoulombpotentialTab = uitab(app.PotChoice);
            app.CoulombpotentialTab.Title = 'Coulomb potential';
            app.FinitebarrierTab = uitab(app.PotChoice);
            app.FinitebarrierTab.Title = 'Finite barrier';
            app.x_barr1EditFieldLabel = uilabel(app.FinitebarrierTab);
            app.x_barr1EditFieldLabel.Text = 'x_barr1';
            app.x_barr1EditField = uieditfield(app.FinitebarrierTab, 'numeric');
            app.x_barr1EditField.Limits = [-Inf 0];
            app.x_barr1EditField.Tooltip = {'X-coordinate where the barrier will begin'};
            app.x_barr1EditField.Value = -2;
            app.x_barr2EditFieldLabel = uilabel(app.FinitebarrierTab);
            app.x_barr2EditFieldLabel.Text = 'x_barr2';
            app.x_barr2EditField = uieditfield(app.FinitebarrierTab, 'numeric');
            app.x_barr2EditField.Limits = [0 Inf];
            app.x_barr2EditField.Tooltip = {'X-coordinate where the barrier will end'};
            app.x_barr2EditField.Value = 2;
            app.y_barr1EditFieldLabel = uilabel(app.FinitebarrierTab);
            app.y_barr1EditFieldLabel.Text = 'y_barr1';
            app.y_barr1EditField = uieditfield(app.FinitebarrierTab, 'numeric');
            app.y_barr1EditField.Limits = [-Inf 0];
            app.y_barr1EditField.Tooltip = {'Y-coordinate where the barrier will begin'};
            app.y_barr1EditField.Value = -0.4;
            app.y_barr2EditFieldLabel = uilabel(app.FinitebarrierTab);
            app.y_barr2EditFieldLabel.Text = 'y_barr2';
            app.y_barr2EditField = uieditfield(app.FinitebarrierTab, 'numeric');
            app.y_barr2EditField.Limits = [0 Inf];
            app.y_barr2EditField.Tooltip = {'Y-coordinate where the barrier will end'};
            app.y_barr2EditField.Value = 0.4;
            app.SteppotentialTab = uitab(app.PotChoice);
            app.SteppotentialTab.Title = 'Step potential';
            app.DoublewelltrapTab = uitab(app.PotChoice);
            app.DoublewelltrapTab.Title = 'Double-well trap';
            app.U0Label = uilabel(app.DoublewelltrapTab);
            app.U0Label.Text = 'U0';
            app.U0Input = uieditfield(app.DoublewelltrapTab, 'numeric');
            app.U0Input.Limits = [0.001 10000];
            app.U0Input.Tooltip = {'Height of the perturbation'};
            app.U0Input.Value = 30;
            app.sigmaLabel = uilabel(app.DoublewelltrapTab);
            app.sigmaLabel.Text = 'sigma';
            app.sigmaInput = uieditfield(app.DoublewelltrapTab, 'numeric');
            app.sigmaInput.Limits = [0.01 Inf];
            app.sigmaInput.Tooltip = {'Width of the perturbation'};
            app.sigmaInput.Value = 0.1;
            app.PowerlawpotentialTab = uitab(app.PotChoice);
            app.PowerlawpotentialTab.Title = 'Power-law potential';
            app.PowerEditFieldLabel = uilabel(app.PowerlawpotentialTab);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.PowerlawpotentialTab, 'numeric');
            app.PowerEditField.Tooltip = {'Power used in the power-law potential.'};
            app.PowerEditField.Value = 1;
            app.n_stEditFieldLabel = uilabel(app.PotentialTab);
            app.n_stEditFieldLabel.Text = 'n_st';
            app.n_stInput = uieditfield(app.PotentialTab, 'numeric');
            app.n_stInput.Tooltip = {'Lowest energy-level to calculate'};
            app.n_stInput.Value = 1;
            app.n_finEditFieldLabel = uilabel(app.PotentialTab);
            app.n_finEditFieldLabel.Text = 'n_fin';
            app.n_finInput = uieditfield(app.PotentialTab, 'numeric');
            app.n_finInput.Tooltip = {'Highest energy-level to calculate'};
            app.n_finInput.Value = 3;
            app.V_0Label = uilabel(app.PotentialTab);
            app.V_0Label.Text = 'V_0';
            app.V0Input = uieditfield(app.PotentialTab, 'numeric');
            app.V0Input.Tooltip = {'The order of magnitude of the potential'};
            app.V0Input.Value = 10;
            app.RecalculateButton_2 = uibutton(app.PotentialTab, 'push');
            app.RecalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @refreshplot, true);
            app.RecalculateButton_2.Tooltip = {'Re-calculates the energies and eigenfunctions.'};
            app.RecalculateButton_2.Text = 'Re-calculate';
            app.SeparatefigureCheckBox = uicheckbox(app.PotentialTab);
            app.SeparatefigureCheckBox.Tooltip = {'Plot all results on a seperate figure.'};
            app.SeparatefigureCheckBox.Text = 'Separate figure';
            app.ModulussquaredSwitchLabel = uilabel(app.PotentialTab);
            app.ModulussquaredSwitchLabel.Text = 'Modulus squared';
            app.ModulussquaredSwitch = uiswitch(app.PotentialTab, 'slider');
            app.ModulussquaredSwitch.Tooltip = {'Choose between plotting the modulus squared or the true wavefunctions.'};
            app.GeometryTab = uitab(app.ModeChoice);
            app.GeometryTab.Tooltip = {'Choose the geometry that will be used.'};
            app.GeometryTab.Title = 'Geometry';
            app.h0Label = uilabel(app.GeometryTab);
            app.h0Label.Text = 'h0';
            app.HmaxInput = uieditfield(app.GeometryTab, 'numeric');
            app.HmaxInput.Limits = [0.01 Inf];
            app.HmaxInput.Tooltip = {'Order of magnitude of the edges of the elements'};
            app.HmaxInput.Value = 0.04;
            app.x_aEditFieldLabel = uilabel(app.GeometryTab);
            app.x_aEditFieldLabel.Text = 'x_a';
            app.x_aInput = uieditfield(app.GeometryTab, 'numeric');
            app.x_aInput.Limits = [-Inf -0.001];
            app.x_aInput.Tooltip = {'Lower bound of the x-axis'};
            app.x_aInput.Value = -1.5;
            app.x_bEditFieldLabel = uilabel(app.GeometryTab);
            app.x_bEditFieldLabel.Text = 'x_b';
            app.x_bInput = uieditfield(app.GeometryTab, 'numeric');
            app.x_bInput.Limits = [0.001 Inf];
            app.x_bInput.Tooltip = {'Upper bound of the x-axis'};
            app.x_bInput.Value = 1.5;
            app.y_aLabel = uilabel(app.GeometryTab);
            app.y_aLabel.Text = 'y_a';
            app.y_aInput = uieditfield(app.GeometryTab, 'numeric');
            app.y_aInput.Limits = [-Inf -0.001];
            app.y_aInput.Tooltip = {'Lower bound of the y-axis'};
            app.y_aInput.Value = -1.5;
            app.y_bLabel = uilabel(app.GeometryTab);
            app.y_bLabel.Text = 'y_b';
            app.y_bInput = uieditfield(app.GeometryTab, 'numeric');
            app.y_bInput.Limits = [0.001 Inf];
            app.y_bInput.Tooltip = {'Upper bound of the y-axis'};
            app.y_bInput.Value = 1.5;
            app.GeomChoice = uitabgroup(app.GeometryTab);
            app.GeomChoice.SelectionChangedFcn = createCallbackFcn(app, @UpdateMesh, true);
            app.RectangleTab = uitab(app.GeomChoice);
            app.RectangleTab.Title = 'Rectangle';
            app.CrossTab = uitab(app.GeomChoice);
            app.CrossTab.Title = 'Cross';
            app.d_xLabel = uilabel(app.CrossTab);
            app.d_xLabel.Text = 'd_x';
            app.d_xInput = uieditfield(app.CrossTab, 'numeric');
            app.d_xInput.Limits = [0.001 Inf];
            app.d_xInput.Tooltip = {'Thickness of the cross in the x-direction'};
            app.d_xInput.Value = 0.5;
            app.d_yLabel = uilabel(app.CrossTab);
            app.d_yLabel.Text = 'd_y';
            app.d_yInput = uieditfield(app.CrossTab, 'numeric');
            app.d_yInput.Limits = [0.001 Inf];
            app.d_yInput.Tooltip = {'Thickness of the cross in the y-direction'};
            app.d_yInput.Value = 0.5;
            app.TriangleTab = uitab(app.GeomChoice);
            app.TriangleTab.Title = 'Triangle';
            app.EllipseTab = uitab(app.GeomChoice);
            app.EllipseTab.Title = 'Ellipse';
            app.MorepointsincenterSwitchLabel = uilabel(app.GeometryTab);
            app.MorepointsincenterSwitchLabel.Text = {'More points'; 'in center'};
            app.MorepointsincenterSwitch = uiswitch(app.GeometryTab, 'slider');
            app.MorepointsincenterSwitch.Tooltip = {'Increases the point density in the center of the geometry and decreases the density at the edges'};
            app.RecalculateButton = uibutton(app.GeometryTab, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMesh, true);
            app.RecalculateButton.Tooltip = {'Re-calculates the mesh, energies and eigenstates'};
            app.RecalculateButton.Text = 'Re-calculate';
            app.TimestepEditFieldLabel = uilabel(app.UIFigure);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TimestepEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimestepEditField.Limits = [0.001 1000];
            app.TimestepEditField.Tooltip = {'Magnitude of one time step.'};
            app.TimestepEditField.Value = 0.25;
            app.E_0EditFieldLabel = uilabel(app.UIFigure);
            app.E_0EditFieldLabel.Text = 'E_0';
            app.E_0EditField = uieditfield(app.UIFigure, 'numeric');
            app.E_0EditField.Editable = 'off';
            app.E_0EditField.Tooltip = {'Calculated ground-state energy'};
            app.E1EditFieldLabel = uilabel(app.UIFigure);
            app.E1EditFieldLabel.Text = 'E^(1)';
            app.E1EditField = uieditfield(app.UIFigure, 'numeric');
            app.E1EditField.Editable = 'off';
            app.E1EditField.Tooltip = {'First-order approximation ground-state energy'};
            app.E2EditFieldLabel = uilabel(app.UIFigure);
            app.E2EditFieldLabel.Text = 'E^(2)';
            app.E2EditField = uieditfield(app.UIFigure, 'numeric');
            app.E2EditField.Editable = 'off';
            app.E2EditField.Tooltip = {'Second-order approximation ground-state energy.'};
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Tooltip = {'Choose the unperturbed potential.'};
            app.PotentialwellButton = uiradiobutton(app.ButtonGroup);
            app.PotentialwellButton.Text = 'Potential well';
            app.PotentialwellButton.Value = true;
            app.HarmonicButton = uiradiobutton(app.ButtonGroup);
            app.HarmonicButton.Text = 'Harmonic';
            app.omegaEditFieldLabel = uilabel(app.UIFigure);
            app.omegaEditFieldLabel.Text = 'omega';
            app.omegaEditField = uieditfield(app.UIFigure, 'numeric');
            app.omegaEditField.Tooltip = {'The value for omega of the harmonic oscillator.'};
            app.omegaEditField.Value = 10;
            app.PertubationtheoryButton = uibutton(app.UIFigure, 'push');
            app.PertubationtheoryButton.ButtonPushedFcn = createCallbackFcn(app, @PertubationtheoryButtonPushed, true);
            app.PertubationtheoryButton.Tooltip = {'Calculates first- and second-order approximations of the ground-state energy using perturbation theory.'};
            app.PertubationtheoryButton.Text = 'Pertubation theory';
            app.InitialconditionButton = uibutton(app.UIFigure, 'push');
            app.InitialconditionButton.ButtonPushedFcn = createCallbackFcn(app, @InitialconditionButtonPushed, true);
            app.InitialconditionButton.Tooltip = {'Will approximate the evolution of the wavefunction after it''s collapse.'};
            app.InitialconditionButton.Text = 'Initial condition';
            app.StepsEditFieldLabel = uilabel(app.UIFigure);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsEditField.Limits = [0 50];
            app.StepsEditField.Tooltip = {'Amount of time steps to include in the calculation.'};
            app.StepsEditField.Value = 1;
            app.CaptureplotsButton = uibutton(app.UIFigure, 'push');
            app.CaptureplotsButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureplotsButtonPushed, true);
            app.CaptureplotsButton.Tooltip = {'Captures all plots in the app; does not work well on smaller screens: instead use ''seperate figure''.'};
            app.CaptureplotsButton.Text = 'Capture plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEM_2D_Schrodinger_Equation_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end